classdef DropDownPlus < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        DropDown    matlab.ui.control.DropDown
        Button      matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = public)
        EditItemsFcn
        HasButton logical = true;
        Items (1,:) string = string.empty;
        ItemsData (1,:) cell = {};
        Value
    end
    
    methods
        function set.Value(comp, value)
            comp.Value = value;
            comp.postSetValue()
        end

        function set.Items(comp, value)
            comp.Items = value;
            comp.postSetItems()
        end
               
        function set.ItemsData(comp, value)
            comp.ItemsData = value;
            comp.postSetItemsData()
        end
    end

    methods (Access = private)
        function postSetValue(comp)
            comp.DropDown.Value = comp.Value;
        end

        function postSetItems(comp)
            comp.DropDown.Items = comp.Items;
        end
                
        function postSetItemsData(comp)
            comp.DropDown.ItemsData = comp.ItemsData;
        end
    end

    methods
        function updateValue(comp, newValue, previousValue)
            comp.Value = newValue;

            evtData = matlab.ui.eventdata.ValueChangedData(...
                newValue, previousValue);

            notify(comp, 'ValueChanged', evtData);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            if ~isempty(comp.EditItemsFcn)

                % Need to pass some metainformation, like what types
                [newItemsData, newItems] = comp.EditItemsFcn(comp.Value);
            
                if ~isempty(newItems) && ~isempty(newItemsData)
                    if comp.DropDown.ValueIndex == 1
                        comp.Items = [comp.Items, newItems];
                        comp.ItemsData = [comp.ItemsData, {newItemsData}];
                        comp.updateValue(newItemsData, comp.DropDown.Value)
                    else
                        comp.Items( comp.DropDown.ValueIndex ) = newItems;
                        comp.ItemsData( comp.DropDown.ValueIndex ) = {newItemsData};
                    end
                end
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(comp, event)
            value = comp.DropDown.Value;
            comp.updateValue(value, event.PreviousValue)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 283 23];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.Button = uibutton(comp.GridLayout, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Icon = fullfile(pathToMLAPP, '', '001_-list-128.png');
            comp.Button.Text = '';
            comp.DropDown = uidropdown(comp.GridLayout);
            comp.DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DropDownValueChanged, true);
        end
    end
end