classdef InstanceDropDownWithEditButton < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout          matlab.ui.container.GridLayout
        InstanceDropDown    om.internal.control.InstanceDropDown
        EditInstanceButton  matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = public)
        EditItemsFcn
        HasButton logical = true;
        Items (1,:) string = string.empty;
        ItemsData (1,:) cell = {};
        Value
    end
    
    methods
        function set.Value(comp, value)
            comp.Value = value;
            comp.postSetValue()
        end

        function set.Items(comp, value)
            comp.Items = value;
            comp.postSetItems()
        end
               
        function set.ItemsData(comp, value)
            comp.ItemsData = value;
            comp.postSetItemsData()
        end
    end

    methods (Access = private)
        function postSetValue(comp)
            comp.DropDown.Value = comp.Value;
        end

        function postSetItems(comp)
            comp.DropDown.Items = comp.Items;
        end
                
        function postSetItemsData(comp)
            comp.DropDown.ItemsData = comp.ItemsData;
        end
    end

    methods
        function updateValue(comp, newValue, previousValue)
            comp.Value = newValue;

            evtData = matlab.ui.eventdata.ValueChangedData(...
                newValue, previousValue);

            notify(comp, 'ValueChanged', evtData);
        end
    end

    methods (Access = private)
        function onParentPropertyChanged(comp, ~, evt)
            if strcmp(evt.Property, 'BackgroundColor')
                comp.updateBackgroundColor()
            end
        end
        function updateBackgroundColor(comp)
            if isa(comp.Parent, 'matlab.ui.Figure')
                backgroundColor = comp.Parent.Color;
            else
                if isprop(comp.Parent, 'BackgroundColor')
                    backgroundColor = comp.Parent.BackgroundColor;
                else
                    S = warning('off', 'backtrace');
                    warning('Could not detect background color.')
                    warning(S);
                end
            end
            comp.BackgroundColor = backgroundColor;
            comp.GridLayout.BackgroundColor = backgroundColor;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            addlistener(comp.Parent, 'BindablePropertyChanged', @comp.onParentPropertyChanged);
            updateBackgroundColor(comp)
        end

        % Button pushed function: EditInstanceButton
        function EditInstanceButtonPushed(comp, event)
            if ~isempty(comp.EditItemsFcn)

                % Need to pass some metainformation, like what types
                [newItemsData, newItems] = comp.EditItemsFcn(comp.Value);
            
                if ~isempty(newItems) && ~isempty(newItemsData)
                    if comp.DropDown.ValueIndex == 1
                        comp.Items = [comp.Items, newItems];
                        comp.ItemsData = [comp.ItemsData, {newItemsData}];
                        comp.updateValue(newItemsData, comp.DropDown.Value)
                    else
                        comp.Items( comp.DropDown.ValueIndex ) = newItems;
                        comp.ItemsData( comp.DropDown.ValueIndex ) = {newItemsData};
                    end
                end
            end
        end

        % Callback function
        function DropDownValueChanged(comp, event)
            value = comp.DropDown.Value;
            comp.updateValue(value, event.PreviousValue)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 283 23];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.EditInstanceButton = uibutton(comp.GridLayout, 'push');
            comp.EditInstanceButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @EditInstanceButtonPushed, true);
            comp.EditInstanceButton.Icon = fullfile(pathToMLAPP, '', '001_-list-128.png');
            comp.EditInstanceButton.Text = '';
            comp.InstanceDropDown = om.internal.control.InstanceDropDown(comp.GridLayout);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end