classdef ListControl < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        IconButton  IconButton
        EditField   matlab.ui.control.EditField
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = public)
        EditItemsFcn
        HasButton logical = true;
        Items (1,:) string = string.empty;
        ItemsData (1,:) cell = {};
        Value (1,:) string = string.empty; % Value of component
    end
    
    methods
        function set.Value(comp, value)
            comp.Value = value;
            comp.postSetValue()
        end
    end

    methods (Access = private)
        function postSetValue(comp)
            comp.EditField.Value = strjoin(comp.Value, '; ');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            pathToMLApp = fileparts(mfilename('fullpath'));
            comp.IconButton.SVGSource = fullfile(pathToMLApp, '001_-edit.svg');
            comp.IconButton.Color = '';
            comp.IconButton.Height = 21;
            comp.IconButton.Width = 21;
            comp.IconButton.Padding = 1;
            comp.IconButton.BackgroundAlpha_Hover = 0.3; % Show semi-transparent background on hover
            comp.IconButton.Tooltip = 'Edit...';
            comp.IconButton.CornerRadius = 5;
            comp.IconButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
        end

        % Callback function
        function ButtonPushed(comp, event)
            disp('clicked')
            if ~isempty(comp.EditItemsFcn)

                % Need to pass some metainformation, like what types
                [items, itemsData] = comp.EditItemsFcn(comp.Items, comp.ItemsData);
                comp.Items = items;
                comp.ItemsData = itemsData;

                % Todo...
                % notify(comp, 'ValueChanged');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 283 23];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.EditField = uieditfield(comp.GridLayout, 'text');
            comp.EditField.Editable = 'off';
            comp.EditField.Value = 'dnksvnjklwjv ivnopw. 2jwvpn wjvnwv qnvoiv inw ovneirov';
            comp.IconButton = IconButton(comp.GridLayout);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end