classdef MixedTypeDropDown < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        DropDown    matlab.ui.control.DropDown
        Button      matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = public)
        EditItemsFcn
        HasButton logical = true;
        Items (1,:) string = string.empty;
        ItemsData (1,:) cell = {};
        Value
    end
    
    properties (Access = private) % Graphical components
        TypeSelectionContextMenu
    end

    properties (Hidden)
        MetadataCollection
    end

    properties (Dependent)
        FairgraphClient
    end

    properties (Access = private)
        FairgraphClient_
    end

    properties (Access = private, AbortSet = true)
        % ActiveType - The metadata type which is currently active/selected 
        % in this component
        ActiveType (1,1) string = missing
    end
    
    properties (Access = private)
        MixedTypeClassName (1,1) string = missing

        % AllowedTypes - String array of class names for allowed metadata 
        % types. Class name will be of the form openminds.core.actors.Person
        AllowedTypes (1,:) string 
    end
    
    properties (Constant)
        Actions = ["<Select a instance>", "<Create a new instance>", "<Download instances>"]
    end

    properties
        SynchInstanceFcn
    end
    
    % Property set methods
    methods
        function set.Value(comp, value)
            comp.Value = value;
            comp.postSetValue()
        end

        function set.Items(comp, value)
            comp.Items = value;
            comp.postSetItems()
        end
               
        function set.ItemsData(comp, value)
            comp.ItemsData = value;
            comp.postSetItemsData()
        end
            
        function set.AllowedTypes(comp, value)
            comp.AllowedTypes = value;
            comp.postSetAllowedTypes()
        end

        function set.ActiveType(comp, value)
            value = comp.validateActiveType(value);
            if strcmp(comp.ActiveType, value); return; end
            comp.ActiveType = value;
            comp.postSetActiveType()
        end
        
        function set.MixedTypeClassName(comp, value)
            assert( ismissing(comp.MixedTypeClassName), ...
                'Can not change value of property "MixedTypeClassName".' )
            comp.MixedTypeClassName = value;
            comp.postSetMixedTypeClassName()
        end

        function fgClient = get.FairgraphClient(comp)
            if isempty(comp.FairgraphClient_)
                comp.initializeFairgraphClient()
            end
            fgClient = comp.FairgraphClient_;
        end
    end

    % Property post-set methods
    methods (Access = private)
        function postSetValue(comp)
            if ismissing(comp.MixedTypeClassName)
                comp.MixedTypeClassName = class(comp.Value);
            end

            if isempty(comp.Value)
                activeType = comp.AllowedTypes(1);
            else
                if isa(comp.Value, "openminds.abstract.Schema")
                    instanceClassName = class( comp.Value );
                elseif isa(comp.Value, "openminds.internal.abstract.LinkedCategory")
                    instanceClassName = class( comp.Value.Instance );
                else
                    error('Unsupported type')
                end
                activeType = comp.validateActiveType(instanceClassName);
            end

            comp.ActiveType = activeType;

            if isempty(comp.Value)
                % Make sure the empty instance in ItemsData is the same 
                % object (handle) as the current Value
                if strcmp( class(comp.Value), class(comp.DropDown.ItemsData{1}) )
                    comp.DropDown.ItemsData{1} = comp.Value;
                end
            end
            
            comp.DropDown.Value = comp.Value;
        end

        function postSetItems(comp)
            actions = comp.getActionsWithTypeLabels();
            items = [actions, comp.Items];
            comp.DropDown.Items = items;
        end
                
        function postSetItemsData(comp)
            % Build items for actions.
            if ~ismissing(comp.ActiveType)
                emptyInstance = feval( sprintf("%s.empty", comp.MixedTypeClassName) );
            else
                emptyInstance = [];
            end
            actionItems = [{emptyInstance}, cellstr(comp.Actions(2:3))];

            itemsData = [actionItems, comp.ItemsData];
            comp.DropDown.ItemsData = itemsData;
        end

        function postSetAllowedTypes(comp)
            comp.createContextMenu()
        end

        function postSetActiveType(comp)
            comp.updateCheckedContextMenuItem()
            comp.updateDropdownItems()
        end

        function postSetMixedTypeClassName(comp)
            
            allowedTypes = eval(sprintf("%s.ALLOWED_TYPES", comp.MixedTypeClassName));
            
            schemaName = om.internal.vocab.getSchemaNameFromMixedTypeClassName(comp.MixedTypeClassName);
            propertyName = openminds.internal.utility.getSchemaShortName( comp.MixedTypeClassName );
            
            comp.AllowedTypes = om.internal.config.sortTypes(schemaName, propertyName, allowedTypes);
        end
    end
    
    % Dropdown action methods
    methods
        function wasSuccess = createNewInstance(comp)
            
            wasSuccess = false;

            emptyInstance = feval(comp.ActiveType);

            schemaName = om.internal.vocab.getSchemaNameFromMixedTypeClassName(comp.MixedTypeClassName);
            schemaClassName = om.internal.vocab.getFullClassNameFromSchemaName(schemaName);

            propertyName = char( openminds.internal.utility.getSchemaShortName( comp.MixedTypeClassName ) );
            propertyName(1) = lower(propertyName(1));

            %propertyTypeName = eval(sprintf("%s.X_TYPE", schemaClassName)) + "/" + propertyName;

            [itemData, item] = om.uiCreateNewInstance(emptyInstance, comp.MetadataCollection, ...
                "UpstreamInstanceType", schemaName, ...
                "UpstreamInstancePropertyName", propertyName);

            if isempty(itemData)
                return
            else
                % Encapsulate the new instance in a mixed type class
                itemData = feval(comp.MixedTypeClassName, itemData);
                
                comp.Items = [comp.Items, item];
                comp.ItemsData = [comp.ItemsData, {itemData}];
                drawnow
                comp.Value = itemData;

                wasSuccess = true;
            end
        end
        
        function wasSuccess = downloadRemoteInstances(comp)
                  
            wasSuccess = false;

            fgClient = comp.FairgraphClient;
            
            activeType = comp.ActiveType;
            fairgraphType = ebrains.kg.internal.convert.getFairgraphType(activeType);

            fgInstance = feval(fairgraphType);

            listedInstances = {};

            hFigure = ancestor(comp, 'figure');
            hProgress = uiprogressdlg(hFigure, 'Message', 'Downloading instances...', 'Indeterminate', true);


            isFinished = false;
            count=0;
            while ~isFinished
                %Todo: hw to apply custom property filters based on
                %templates/configs, i.e only get strains for individual species
                result = fgInstance.list(comp.FairgraphClient, size=uint8(100), from_index=uint8(count));

                if isempty( cell(result) )
                    isFinished = true;
                else
                    listedInstances = [listedInstances, cell(result)];
                    count = numel(listedInstances);
                end
                hProgress.Message = sprintf('Downloading instances... Received %d...', numel(listedInstances) );
            end

            hProgress.Indeterminate = false;
            hProgress.Message = "Processing instances...";

            %convert to openminds
            omInstances = feval(sprintf( '%s.empty', comp.ActiveType) );
            for i = 1:numel(listedInstances)
                omInstances(i) = ebrains.kg.internal.convert.fairgraph2openminds(listedInstances{i}, fgClient);
                hProgress.Value = 1/numel(listedInstances);
                hProgress.Message = sprintf("Processing instances (%d/%d done)...", i, numel(listedInstances));
            end

            delete(hProgress)

            % Add to collection

            % Get labels

            % Encapsulate with mixed type class

            % Append to items/itemsdata

            % itemData = feval(comp.MixedTypeClassName, itemData);
            disp('a')

            wasSuccess = true;
        end
    
    end

    methods
        function updateValue(comp, newValue, previousValue)
            comp.Value = newValue;

            evtData = matlab.ui.eventdata.ValueChangedData(...
                newValue, previousValue);

            notify(comp, 'ValueChanged', evtData);
        end
    end

    methods (Access = private)
        function value = validateActiveType(comp, value)
        % validateActiveType - Validate value for ActiveType property

            isValid = any( strcmp(comp.AllowedTypes, value) );
            
            if ~isValid
                % Todo: This should not be necessary
                mc = meta.class.fromName(value);
                typeAlias = mc.Aliases;
                
                isValid = any( strcmp(comp.AllowedTypes, typeAlias) );
                if isValid
                    value = typeAlias;
                end
            end
            
            errorMessage = sprintf( 'Active type must be any of %s', ...
                    strjoin(comp.AllowedTypes, ', '));

            assert(isValid, errorMessage)
        end

        % Create context menu for selecting active type
        function createContextMenu(comp, hFigure)
        % createContextMenu - Create context menu for selecting active type
            if nargin < 2; hFigure = ancestor(comp, 'figure'); end

            if ~isempty(hFigure) && isvalid(hFigure)
                if isempty(comp.TypeSelectionContextMenu)
                    comp.TypeSelectionContextMenu = uicontextmenu(hFigure);
                end
                
                if ~isempty(comp.TypeSelectionContextMenu.Children)
                    delete(comp.TypeSelectionContextMenu.Children)
                end

                for i = 1:numel(comp.AllowedTypes)
                    iType = comp.AllowedTypes(i);
                    typeShortName = openminds.internal.utility.getSchemaShortName(iType);

                    typeMenuItem = uimenu(comp.TypeSelectionContextMenu);
                    typeMenuItem.Text = typeShortName;
                    typeMenuItem.Callback = @comp.onMetadataTypeContextMenuItemClicked;
                    typeMenuItem.Checked = 'off';
                end
            end
        end

        function onMetadataTypeContextMenuItemClicked(comp, src, event)
            fullClassName = comp.findMatchingClassName(event.Source.Text, comp.AllowedTypes);
            comp.ActiveType = fullClassName;
        end

        function changeType(comp, newType)
            comp.DropDown.Placeholder = sprintf("Create a new %s", newType);
        end        

        function updateCheckedContextMenuItem(comp)
            % Uncheck all menu items
            set(comp.TypeSelectionContextMenu.Children, 'Checked', 'off');

            if ismissing(comp.ActiveType)
                % pass
            else
                menuItemLabels = {comp.TypeSelectionContextMenu.Children.Text};
                activeTypeShortName = openminds.internal.utility.getSchemaShortName(comp.ActiveType);

                isMatch = strcmp(menuItemLabels, activeTypeShortName);
                if any(isMatch)
                    comp.TypeSelectionContextMenu.Children(isMatch).Checked = "on";
                else
                    error('Unexpected')
                end
            end
        end
    
        function updateDropdownItems(comp)
            items = string.empty;
            itemsData = {};

            if ismissing(comp.ActiveType)
                % pass
            else
                schemaName = openminds.internal.utility.getSchemaShortName(comp.ActiveType);
                itemsData = comp.MetadataCollection.list(schemaName);
                if ~isempty(itemsData)
                    items = arrayfun(@(i) string(char(i)), itemsData);
                end
                itemsDataCell = cell(1, numel(itemsData));
                for i = 1:numel(itemsData)
                    itemsDataCell{i} = feval(comp.MixedTypeClassName, itemsData(i));
                end
                itemsData = itemsDataCell;
            end
            comp.Items = items;
            comp.ItemsData = itemsData;
        end

        function actions = getActionsWithTypeLabels(comp)
            actions = comp.Actions;

            if ismissing(comp.ActiveType)
                return
            end
            
            activeTypeShortName = openminds.internal.utility.getSchemaShortName(comp.ActiveType);
            
            % Get label from vocab
            label = om.internal.vocab.getSchemaLabelFromName(activeTypeShortName);

            vowels = 'aeiouy';
            label = char(label);
            startsWithVowel = any( label(1) == vowels );

            actions = strrep(actions, 'instance', sprintf('"%s" instance', lower(label)));
            if startsWithVowel
                actions = strrep(actions, 'Select a', 'Select an');
            end
        end

        function initializeFairgraphClient(comp)
            % Todo: Get from singleton.
            authClient = ebrains.iam.AuthenticationClient.instance();
            comp.FairgraphClient_ = py.fairgraph.KGClient( authClient.AccessToken ); 
        end
    end

    methods (Static, Access = private)
        function fullClassName = findMatchingClassName(value, options)
            optionsShort = arrayfun(@(str) openminds.internal.utility.getSchemaShortName(str), options);
            isMatch = strcmp(optionsShort, value);
            fullClassName = options(isMatch);
        end
    end

    methods (Access = private)
        function onParentPropertyChanged(comp, ~, evt)
            if strcmp(evt.Property, 'BackgroundColor')
                comp.updateBackgroundColor()
            end
        end
        function updateBackgroundColor(comp)
            if isa(comp.Parent, 'matlab.ui.Figure')
                backgroundColor = comp.Parent.Color;
            else
                if isprop(comp.Parent, 'BackgroundColor')
                    backgroundColor = comp.Parent.BackgroundColor;
                else
                    S = warning('off', 'backtrace');
                    warning('Could not detect background color.')
                    warning(S);
                end
            end
            comp.BackgroundColor = backgroundColor;
            comp.GridLayout.BackgroundColor = backgroundColor;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            hFigure = ancestor(comp, 'figure');
            comp.createContextMenu(hFigure)
            addlistener(comp.Parent, 'BindablePropertyChanged', @comp.onParentPropertyChanged);
            updateBackgroundColor(comp)
        end

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            pos = getpixelposition(event.Source, true);
            comp.TypeSelectionContextMenu.open(pos(1), pos(2))
        end

        % Value changed function: DropDown
        function DropDownValueChanged(comp, event)
            value = comp.DropDown.Value;
            
            if isequal(value, comp.Actions(2)) || isequal(value, comp.Actions(3))
                switch value
                    case comp.Actions(2)
                        wasSuccess = comp.createNewInstance();
                    case comp.Actions(3)
                        wasSuccess = comp.downloadRemoteInstances();
                        comp.DropDown.Value = comp.Value;
                end
                if ~wasSuccess % Reset dropdown selection
                    comp.DropDown.Value = comp.Value;
                end
            else
                comp.updateValue(value, event.PreviousValue)
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.postSetItems()
            comp.postSetItemsData()
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 283 23];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.Button = uibutton(comp.GridLayout, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Icon = fullfile(pathToMLAPP, '001_-classification.png');
            comp.Button.Text = '';
            comp.DropDown = uidropdown(comp.GridLayout);
            comp.DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DropDownValueChanged, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end