classdef ArrayEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        InteractiveListMenu  structeditor.component.InteractiveListMenu
        ControlPanel         matlab.ui.container.Panel
        ItemDropDown         matlab.ui.control.DropDown
        EditItemLabel        matlab.ui.control.Label
    end

    properties (SetAccess = private)
        Data
    end

    properties
        NameField (1,1) string = missing
        NameFcn = []
        ItemType
        Title (1,1) string = "Edit Struct Array"
    end

    properties (Access = private)
        DefaultItem
        CurrentIdx = []
    end

    properties (Access = private)
        ItemNodeList matlab.ui.container.TreeNode % Description

        UIControlContainers
    end
    
    methods (Access = private)
        
        function onSelectedItemChanged(app, src, evt)
            disp(evt.OldIndex)
            prevIdx = app.CurrentIdx;
            
            updatedData = app.UIControlContainers.Data;
            app.Data(prevIdx) = updatedData;

            data = app.Data(evt.NewIndex);
            app.UIControlContainers.Data = data;

            app.CurrentIdx = evt.NewIndex;
        end

        function onItemAdded(app, src, evt)

            if ~isempty(app.UIControlContainers)
                updatedData = app.UIControlContainers.Data;
                app.Data(app.CurrentIdx) = updatedData;
            else
                app.createForm()
            end

            % Todo: get selected type...
            if isempty(app.Data)
                app.Data = app.DefaultItem;
            else
                app.Data(end+1) = app.DefaultItem;
            end
            app.CurrentIdx = numel(app.Data);
            
            app.UIControlContainers.Data = app.DefaultItem;
        end

    end

    methods
        function set.Title(app, value)
            app.Title = value;
            app.postSetTitle()
        end
    end
   
    methods (Access = private)
        function postSetTitle(app)
            app.UIFigure.Name = app.Title;
        end
    end

    methods (Access = private)
        function createForm(app, data)
            if nargin < 2
                data = app.DefaultItem;
            end
            H = structeditor.UIControlContainer(app.ControlPanel, data);
            app.UIControlContainers = H;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, options)
            
            arguments
                app
                data
                options.NameField (1,1) string = missing
                options.NameFcn = []
                options.ItemType (1,1) string = missing
                options.Title (1,1) string = "Edit Struct Array"
                options.DefaultItem = {}
            end

            for iFieldName = string( fieldnames(options)' )
                app.(iFieldName) = options.(iFieldName);
            end
            
            app.InteractiveListMenu.ItemSelectedFcn = @app.onSelectedItemChanged;
            app.InteractiveListMenu.ItemAddedFcn = @app.onItemAdded;

            app.Data = data;
            if isempty(app.DefaultItem)
                app.DefaultItem = structeditor.utility.resetStructData(data(1));
            end

            if ~ismissing(app.ItemType)
                app.InteractiveListMenu.ItemType = app.ItemType;
            end

            % Todo: Get labels.
            for i = 1:numel(app.Data)

                if ~ismissing(app.NameField)
                    itemName = app.Data(i).(app.NameField);
                elseif ~isempty(app.NameFcn)
                    itemName = app.NameFcn( app.Data(i) );
                else
                    itemName = '';
                end
                
                app.InteractiveListMenu.addItem(itemName)
            end

            if ~isempty(  app.Data )
                app.createForm( app.Data(1) )
                app.CurrentIdx = 1;
            else
                app.CurrentIdx = 0;
            end
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case 'backspace'
                    if isa(app.UIFigure.CurrentObject, 'matlab.ui.container.TreeNode')
                        app.InteractiveListMenu.removeItem( app.UIFigure.CurrentObject )
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EditItemLabel = uilabel(app.GridLayout);
            app.EditItemLabel.Text = 'Edit Item';
            app.ItemDropDown = uidropdown(app.GridLayout);
            app.ItemDropDown.Items = {'<Create New Item>', 'Option 1', 'Option 2', 'Option 3', 'Option 4'};
            app.ItemDropDown.Value = '<Create New Item>';
            app.ControlPanel = uipanel(app.GridLayout);
            app.InteractiveListMenu = structeditor.component.InteractiveListMenu(app.GridLayout);
            app.InteractiveListMenu.Identifier = '41a15c5e-e605-4b48-998b-812fb0b53237';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArrayEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end