classdef HeterogeneousArrayEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        InteractiveListMenu      structeditor.component.InteractiveListMenu
        SelectmetadatatypeLabel  matlab.ui.control.Label
        TypeDropDown             matlab.ui.control.DropDown
        ItemDropDown             matlab.ui.control.DropDown
        FinishButtons            structeditor.FinishButtons
        ControlPanel             matlab.ui.container.Panel
        EditItemLabel            matlab.ui.control.Label
    end

    properties (SetAccess = private)
        Data
    end

    properties
        NameField (1,1) string = missing
        NameFcn = []
        ItemType
        Title (1,1) string = "Edit Struct Array"
    end

    properties (Access = private)
        DefaultItem
        CurrentIdx = []
        ItemTypes
        ItemLabels
    end

    properties (Access = private)
        UIControlContainers (1,:) structeditor.UIControlContainer
        ContainerTypeMap (1,:) string
        StructTypeMap (1,:) string

        TypeSelectorRadioButtons (1,:) matlab.ui.control.StateButton
    end

    % Dialog window properties.
    properties (SetAccess = private)
        FinishState (1,1) string {mustBeMember(FinishState, ["", "Finished", "Canceled"])} = ""
        CloseOnExit (1,1) logical = true
        MetadataCollection
    end

    properties (Access = private)
        % Todo: rename... Is true if uiwait is active...
        IsStandalone (1,1) logical = true
        InstanceDropDown
    end
    
    
    % Todo:
    %   [ ] Work both with dropdown or radiobuttons for selecting type.
    %   [ ] Populate dropdown with existing items...


    methods
        function set.Title(app, value)
            app.Title = value;
            app.postSetTitle()
        end
    end

    methods
        function [data, names] = getData(app)

            data = app.Data;
            names = cell(1,numel(data));

            for i = 1:numel(data)

                name = app.InteractiveListMenu.getItemName(i);
    
                % Define the regular expression pattern
                pattern = '^(?<name>.+?)(\s*\((?<annotation>.+?)\))?$';
                
                % Get current name and annotation
                tokens = regexp(name, pattern, 'names');
                names{i} =  tokens.name;
            end
        end
    end
    
    % Methods giving this app dialog like behavior
    methods
        function uiwait(app, preventClose)
            if nargin == 2
                app.CloseOnExit = ~preventClose;
            end
            app.IsStandalone = false;
            app.FinishState = "";
            uiwait(app.UIFigure) %#ok<ADMTHDINV>
        end
   
        function show(app)
            app.UIFigure.Visible = 'on';
        end

        function hide(app)
            app.UIFigure.Visible = 'off';
        end

        function tf = hasFigure(app)
            tf = isvalid(app.UIFigure);
        end
    end
    
    % Callback functions
    methods (Access = private)
        
        function onSelectedItemChanged(app, src, evt)

            prevIdx = app.CurrentIdx;

            if prevIdx == evt.NewIndex
                return
            end
            
            % Call drawnow to make sure value changed callbacks have time
            % to finish updating (might not be necessary)
            drawnow; pause(0.05)

            % Hide currently visible container
            if ~isempty(prevIdx)
                % Get data for current form and update Data
                app.updateDataFromForm()           
    
                previousType = app.StructTypeMap(prevIdx);
                prevContainerIdx = app.getContainerTypeIndex(previousType);
                app.UIControlContainers(prevContainerIdx).Visible = 'off';
            end

            % Update data for selected form
            data = app.Data{evt.NewIndex};
            
            newType = app.StructTypeMap(evt.NewIndex);
            newContainerIdx = app.getContainerTypeIndex(newType);
            if isempty(newContainerIdx)
                app.createForm(newType, data);
            else
                app.UIControlContainers(newContainerIdx).Data = data;
                app.UIControlContainers(newContainerIdx).Visible = 'on';
            end
            
            % Update current index
            app.CurrentIdx = evt.NewIndex;

            % Update selected type in type selection component based on
            % type of selected item
            selectedType = app.StructTypeMap(app.CurrentIdx);
            
            idx = find(strcmp({app.TypeSelectorRadioButtons.Tag}, selectedType));
            [app.TypeSelectorRadioButtons(:).Value] = deal(false);
            app.TypeSelectorRadioButtons(idx).Value = 1;
        end

        function onItemAdded(app, src, evt)

            % Get data for current form and update Data
            app.updateDataFromForm()

            % Todo: get selected type...
            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                %selectedType = app.TypeDropDown.Value;
                selectedType = app.getCurrentType();
                newStruct = app.DefaultItem.getType(selectedType);
            else
                selectedType = string(missing);
                newStruct = app.DefaultItem;
            end
            
            app.Data{end+1} = newStruct;
            app.CurrentIdx = numel(app.Data);
            app.StructTypeMap(app.CurrentIdx) = selectedType;
            
            if ~ismissing(selectedType)
                for i = 1:numel(app.UIControlContainers)
                    app.UIControlContainers(i).Visible = 'off';
                end
                if ~isempty( app.getContainerTypeIndex(selectedType) )
                    idx = app.getContainerTypeIndex(selectedType);
                    app.UIControlContainers(idx).Visible = 'on';
                else
                    app.createForm(selectedType, newStruct);
                    idx = numel(app.UIControlContainers);
                end
            else
                error('Unexpected.')
                %app.UIControlContainers.Data = app.DefaultItem;
            end
            
            %app.UIControlContainers(idx).Data = newStruct;
            
            % Add itemtype in parenthesis.
            typeName = app.getCurrentType("short");
            app.updateListItemName(app.CurrentIdx, typeName)
            app.InteractiveListMenu.SelectedItemIndex = app.CurrentIdx;
        end
        
        function onItemRemoved(app, src, evt)
            
            app.Data(app.CurrentIdx) = [];
            
            previousType = app.StructTypeMap(app.CurrentIdx);
            app.StructTypeMap(app.CurrentIdx) = [];

            app.hideForm(previousType)
            app.CurrentIdx = [];
            return

            nextIndex =  max( [1, app.CurrentIdx - 1] );
            if nextIndex > numel(app.Data)
                app.hideForm(previousType)
                app.CurrentIdx = [];

            else % Select new item...
                nextType = app.StructTypeMap(nextIndex);
                % Todo: make new method, should be called from
                % onItemSelected, onItemAdded and onItemRemoved

                if ~strcmp( nextType, previousType )
                    app.hideForm(previousType)
                    app.showForm(nextType)
                end
                app.updateFormData(nextType, app.Data{nextIndex})
                app.InteractiveListMenu.SelectedItemIndex = nextIndex;
                app.CurrentIdx = nextIndex;
            end
        end

        function onTypeSelectorButtonPushed(app, src, evt)
            
            % Loop through all buttons to toggle them off except the clicked one
            for i = 1:length(app.TypeSelectorRadioButtons)
                if app.TypeSelectorRadioButtons(i) ~= src
                    app.TypeSelectorRadioButtons(i).Value = false;
                end
            end
            
            % Toggle the clicked button
            src.Value = true;

            app.changeSelectedType()
        end

        function onFinishedButtonPushed(app, src, evt)
            
            app.FinishState = evt.FinishState;

            if app.FinishState == "Finished" % Update date
                app.updateDataFromForm()
                %app.Data = {app.UIControlContainers.Data};
            end
            
            app.close()
        end

        function onUIFigureCloseRequest(app, src, event)
            if app.CloseOnExit
                uiresume(app.UIFigure)
                drawnow
                pause(0.05)
                
                delete(app.UIControlContainers)
                delete(app.UIFigure)
            else
                uiresume(app.UIFigure)
            end
        end

        function hideForm(app, formType)
        %hideForm - Hide form for given form type
            formIndex = app.getContainerTypeIndex(formType);
            if ~isempty(formIndex)
                app.UIControlContainers(formIndex).Visible = 'off';
            end
        end

        function showForm(app, formType)
        %showForm - Show form for given form type
            formIndex = app.getContainerTypeIndex(formType);
            if ~isempty(formIndex)
                app.UIControlContainers(formIndex).Visible = 'on';
            end
        end


        function changeContainerType(app)
            
            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                %selectedType = app.TypeDropDown.Value;
                selectedType = app.getCurrentType();
                newStruct = app.DefaultItem.getType(selectedType);
            else
                selectedType = string(missing);
                newStruct = app.DefaultItem;
            end


            if ~ismissing(selectedType)
                for i = 1:numel(app.UIControlContainers)
                    app.UIControlContainers(i).Visible = 'off';
                end
                if ~isempty( app.getContainerTypeIndex(selectedType) )
                    idx = app.getContainerTypeIndex(selectedType);
                    app.UIControlContainers(idx).Visible = 'on';
                else
                    H = structeditor.UIControlContainer(app.ControlPanel, newStruct);
                    app.UIControlContainers(end+1) = H;
                    app.ContainerTypeMap(end+1) = selectedType;
                    idx = numel(app.UIControlContainers);
                end
            else
                error('Unexpected.')
                %app.UIControlContainers.Data = app.DefaultItem;
            end

        end

        function idx = getContainerTypeIndex(app, typeName)
            idx = find( strcmp(app.ContainerTypeMap, typeName) );
        end

        function createTypeSelectorRadioButtons(app)

            availableTypes = app.DefaultItem.TypeNames;

            for i = 1:numel(availableTypes)
                splitNames = strsplit(availableTypes{i}, '.');
                availableTypes{i} = splitNames{end};
            end

            N = numel(availableTypes);

            % Create a grid layout with 1 row and N columns
            grid = uigridlayout(app.GridLayout, [1, N]);
            grid.Layout.Row = 2;
            grid.Layout.Column = 2;
            grid.Padding = 0;
        
            % Loop to create buttons in each cell
            for i = 1:N
                % Create a button and set its text to 'Button i'
                btn = uibutton(grid, "state", "Text", availableTypes{i});
                btn.Tag = app.DefaultItem.TypeNames(i);
                btn.ValueChangedFcn = @app.onTypeSelectorButtonPushed;

                % Set the button layout to the i-th column
                btn.Layout.Column = i;
                btn.Layout.Row = 1;

                app.TypeSelectorRadioButtons(i) = btn;
            end
            app.TypeSelectorRadioButtons(1).Value = true;
        end
    end

    methods
        function selectedType = getCurrentType(app, mode)
            
            if nargin < 2; mode = "full"; end

            if ~isempty( app.TypeSelectorRadioButtons )
                idx = find([app.TypeSelectorRadioButtons.Value]);
                selectedType = app.DefaultItem.TypeNames(idx);
            else
                selectedType = app.TypeDropDown.Value;
            end

            if mode == "short"
                selectedTypeSplit = strsplit(selectedType, '.');
                selectedType = selectedTypeSplit{end};
            end
        end

        function changeSelectedType(app)

            if app.CurrentIdx >= 1

                currentType = app.StructTypeMap(app.CurrentIdx);

                % Check if form is dirty and open dialog.
                idx = app.getContainerTypeIndex(currentType);
                if not( app.UIControlContainers(idx).IsClean() )
                    msg = "Changing type will discard current changes.";
                    title = "Confirm change";
                    selection = uiconfirm(app.UIFigure, msg, title, ...
                        "Options",["Update Type","Cancel"], ...
                        "DefaultOption",1,"CancelOption",2);
                    if selection == "Cancel"
                        return
                    end
                end
                
                % Get instance type and default data
                if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                    %selectedType = app.TypeDropDown.Value;
                    selectedType = app.getCurrentType();
                    newStruct = app.DefaultItem.getType(selectedType);
                else
                    selectedType = string(missing);
                    newStruct = app.DefaultItem;
                end

                typeName = app.getCurrentType("short");
                app.updateListItemName(app.CurrentIdx, typeName)
            
                % Update instance data
                app.Data{app.CurrentIdx} = newStruct;

                % Update app.StructTypeMap
                app.StructTypeMap(app.CurrentIdx) = selectedType;

                app.changeContainerType()
                idx = app.getContainerTypeIndex(selectedType);
                app.UIControlContainers(idx).Data = newStruct;
            else
                % Do nothing.
            end
        end

        function updateListItemName(app, listItemIndex, typeName)
            
            name = app.InteractiveListMenu.getItemName(listItemIndex);

            % Define the regular expression pattern
            pattern = '^(?<name>.+?)(\s*\((?<annotation>.+?)\))?$';
            
            % Get current name and annotation
            tokens = regexp(name, pattern, 'names');

            % Make sure type name is shortened
            selectedTypeSplit = strsplit(typeName, '.');
            typeName = selectedTypeSplit{end};

            name = app.createItemName(tokens.name, typeName);
            app.InteractiveListMenu.renameItem(listItemIndex, name)
        end
    end

    methods (Access = private)
        function postSetTitle(app)
            app.UIFigure.Name = app.Title;
        end
    end
    
    methods (Access = private)
        function updateDataFromForm(app)
        % updateDataFromForm - Update Data property based on info in current form
            
            if ~isempty(app.UIControlContainers)
                currentType = app.StructTypeMap(app.CurrentIdx);
                currentContainerIdx = app.getContainerTypeIndex(currentType);
                updatedData = app.UIControlContainers(currentContainerIdx).Data;
                app.Data{app.CurrentIdx} = updatedData;
            end
        end

        function updateFormData(app, formType, formData)
            if ~isempty(app.UIControlContainers)
                formIdx = app.getContainerTypeIndex(formType);
                app.UIControlContainers(formIdx).Data = formData;
            end
        end
    
        function createForm(app, formType, formData)
            h = structeditor.UIControlContainer(app.ControlPanel, formData);
            app.UIControlContainers(end+1) = h;
            app.ContainerTypeMap(end+1) = formType;
        end
    
        function name = createItemName(~, name, type)
            name = sprintf('%s (%s)', name, type);

        end
    end
    
    methods (Access = private)
        function close(app)
            app.onUIFigureCloseRequest()
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, options)
            
            arguments
                app
                data
                options.NameField (1,1) string = missing
                options.NameFcn = []
                options.ItemType (1,1) string = missing
                options.Title (1,1) string = "Edit Struct Array"
                options.DefaultItem = {}
                options.MetadataCollection
            end

            uim.utility.centerFigure(app.UIFigure) % Todo: do not use external function for this

            for iFieldName = string( fieldnames(options)' )
                app.(iFieldName) = options.(iFieldName);
            end
            
            % Assign plugin component callbacks
            app.InteractiveListMenu.ItemSelectedFcn = @app.onSelectedItemChanged;
            app.InteractiveListMenu.ItemAddedFcn = @app.onItemAdded;
            app.InteractiveListMenu.ItemRemovedFcn = @app.onItemRemoved;
            
            app.FinishButtons.FinishButtonPushedFcn = @app.onFinishedButtonPushed;

            app.Data = data;
            if isempty(app.DefaultItem)
                app.DefaultItem = structeditor.utility.resetStructData(data(1));
            end

            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                if numel(app.DefaultItem.TypeNames) <= 4
                    app.createTypeSelectorRadioButtons()
                    app.TypeDropDown.Visible = 'off';
                else
                    app.TypeDropDown.Items = app.DefaultItem.TypeNames;
                end
            end

            if ~ismissing(app.ItemType)
                app.InteractiveListMenu.ItemType = app.ItemType;
            end

            % Todo: Get labels.
            for i = 1:numel(app.Data)

                if ~ismissing(app.NameField)
                    itemName = app.Data{i}.(app.NameField);
                elseif ~isempty(app.NameFcn)
                    itemName = app.NameFcn( app.Data{i} );
                else
                    itemName = '';
                end

                app.InteractiveListMenu.addItem(itemName)
                
                itemType = app.DefaultItem.getStructType(app.Data{i});
                app.updateListItemName(i, itemType)
                selectedType = app.DefaultItem.getStructType(app.Data{i});
                app.StructTypeMap(i) = selectedType;
            end

            if ~isempty( app.Data )

                H = structeditor.UIControlContainer(app.ControlPanel, app.Data{1});
                app.UIControlContainers = H;

                app.CurrentIdx = 1;
                selectedType = app.DefaultItem.getStructType(app.Data{1});
                app.ContainerTypeMap(1) = selectedType;
                app.InteractiveListMenu.SelectedItemIndex = app.CurrentIdx;
            else
                app.CurrentIdx = 0;
            end

            app.InstanceDropDown = om.internal.control.InstanceDropDown(...
                app.GridLayout,...
                MetadataType=om.enum.Types(options.ItemType).ClassName, ...
                ActiveMetadataType=options.ItemType, ...
                MetadataCollection=app.MetadataCollection);

            app.InstanceDropDown.Layout.Row = 4;
            app.InstanceDropDown.Layout.Column = 2;
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case 'backspace'
                    if isa(app.UIFigure.CurrentObject, 'matlab.ui.container.TreeNode')
                        app.InteractiveListMenu.removeItem( app.UIFigure.CurrentObject )
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EditItemLabel = uilabel(app.GridLayout);
            app.EditItemLabel.Text = 'Edit Item';
            app.ControlPanel = uipanel(app.GridLayout);
            app.FinishButtons = structeditor.FinishButtons(app.GridLayout);
            app.ItemDropDown = uidropdown(app.GridLayout);
            app.ItemDropDown.Items = {'<Create New Item>', 'Option 1', 'Option 2', 'Option 3', 'Option 4'};
            app.ItemDropDown.Value = '<Create New Item>';
            app.TypeDropDown = uidropdown(app.GridLayout);
            app.TypeDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4'};
            app.TypeDropDown.Value = 'Type 1';
            app.SelectmetadatatypeLabel = uilabel(app.GridLayout);
            app.SelectmetadatatypeLabel.Text = 'Select metadata type';
            app.InteractiveListMenu = structeditor.component.InteractiveListMenu(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeterogeneousArrayEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end