classdef Metadata < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout             matlab.ui.container.GridLayout
        FileSelector           wt.FileSelector
        DatasetShortNameLabel  matlab.ui.control.Label
        DatasetTitleEditField  matlab.ui.control.EditField
        MetadataCollectionRootDirectoryLabel  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        DatasetRootPathValueChanged
        DatasetTitleValueChanged
    end

    properties (Access = public)
        DatasetRootPath (1,1) string % Root directory of a dataset
        DatasetTitle (1,1) string % Title of dataset
    end
    
    methods (Access = public)
        function setFolderHistory(comp, value)
            comp.FileSelector.History = value;
        end

        function value = getFolderHistory(comp)
            value = comp.FileSelector.History;
        end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DatasetTitleEditField
        function DatasetTitleEditFieldValueChanged(comp, event)
            value = comp.DatasetTitleEditField.Value;
            comp.DatasetTitle = value;
            notify(comp, 'DatasetTitleValueChanged');
        end

        % Callback function
        function FileSelectorValueChanged(comp, event)
            value = comp.FileSelector.Value;
            comp.DatasetRootPath = value;
            notify(comp, 'DatasetRootPathValueChanged');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
            if comp.DatasetRootPath ~= comp.FileSelector.Value
                comp.FileSelector.Value = comp.DatasetRootPath;
            end

            if comp.DatasetTitle ~= comp.DatasetTitleEditField.Value
                comp.DatasetTitleEditField.Value = comp.DatasetTitle;
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 560 226];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.MetadataCollectionRootDirectoryLabel = uilabel(comp.GridLayout);
            comp.MetadataCollectionRootDirectoryLabel.Text = 'Metadata Collection Root Directory';
            comp.DatasetTitleEditField = uieditfield(comp.GridLayout, 'text');
            comp.DatasetTitleEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DatasetTitleEditFieldValueChanged, true);
            comp.DatasetShortNameLabel = uilabel(comp.GridLayout);
            comp.DatasetShortNameLabel.Text = 'Dataset Short Name';
            comp.FileSelector = wt.FileSelector(comp.GridLayout);
        end
    end
end