classdef Product < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout           matlab.ui.container.GridLayout
        SoftwareButton       matlab.ui.control.StateButton
        MetadataModelButton  matlab.ui.control.StateButton
        ModelButton          matlab.ui.control.StateButton
        DatasetButton        matlab.ui.control.StateButton
    end

    properties (Access = public)
        SelectedProduct (1,1) string = "";
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.DatasetButton.Icon = om.internal.getIconPath('dataset');
            comp.ModelButton.Icon = om.internal.getIconPath('model');
            comp.SoftwareButton.Icon = om.internal.getIconPath('software2');
            comp.MetadataModelButton.Icon = om.internal.getIconPath('metadata_model');

            comp.DatasetButton.Tag = "Dataset";
            comp.ModelButton.Tag = "Model";
            comp.SoftwareButton.Tag = "Software";
            comp.MetadataModelButton.Tag = "MetaDataModel";

            mc = metaclass(openminds.core.Dataset);
            comp.DatasetButton.Tooltip = mc.Description;

            mc = metaclass(openminds.core.Model);
            comp.ModelButton.Tooltip = mc.Description;

            mc = metaclass(openminds.core.Software);
            comp.SoftwareButton.Tooltip = mc.Description;

            mc = metaclass(openminds.core.MetaDataModel);
            comp.MetadataModelButton.Tooltip = mc.Description;

            % % comp.DatasetButton.BackgroundColor = om.wizard.WizardTheme.LightPrimaryColor;
            % % comp.ModelButton.BackgroundColor = om.wizard.WizardTheme.LightPrimaryColor;
            % % comp.SoftwareButton.BackgroundColor = om.wizard.WizardTheme.LightPrimaryColor;
            % % comp.MetadataModelButton.BackgroundColor = om.wizard.WizardTheme.LightPrimaryColor;
        end

        % Value changed function: DatasetButton, MetadataModelButton, 
        % ...and 2 other components
        function ProductButtonValueChanged(comp, event)
            set([comp.SoftwareButton, comp.MetadataModelButton, comp.ModelButton, comp.DatasetButton], 'Value', false)
            event.Source.Value = true;
            comp.SelectedProduct = event.Source.Tag;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 560 226];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.DatasetButton = uibutton(comp.GridLayout, 'state');
            comp.DatasetButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ProductButtonValueChanged, true);
            comp.DatasetButton.Text = 'Dataset';
            comp.ModelButton = uibutton(comp.GridLayout, 'state');
            comp.ModelButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ProductButtonValueChanged, true);
            comp.ModelButton.Text = 'Model';
            comp.MetadataModelButton = uibutton(comp.GridLayout, 'state');
            comp.MetadataModelButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ProductButtonValueChanged, true);
            comp.MetadataModelButton.Text = 'Metadata Model';
            comp.SoftwareButton = uibutton(comp.GridLayout, 'state');
            comp.SoftwareButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ProductButtonValueChanged, true);
            comp.SoftwareButton.Text = 'Software';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end